classdef AppRobotH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MandoTab                    matlab.ui.container.Tab
        Panel1Principal             matlab.ui.container.Panel
        ButtonArriba                matlab.ui.control.Button
        ButtonAbajo                 matlab.ui.control.Button
        ButtonIzquierda             matlab.ui.control.Button
        ButtonDerecha               matlab.ui.control.Button
        ButtonNeutro                matlab.ui.control.Button
        ledConexionRB               matlab.ui.control.Lamp
        btnConexionRB               matlab.ui.control.StateButton
        labelErorrdeconexion        matlab.ui.control.Label
        IDEditFieldLabel            matlab.ui.control.Label
        fieldIDConexionRB           matlab.ui.control.EditField
        VelocidadSliderLabel        matlab.ui.control.Label
        VelocidadSlider             matlab.ui.control.Slider
        btnConexionRBVideoControl   matlab.ui.control.StateButton
        VideoLabel                  matlab.ui.control.Label
        SwitchVideo                 matlab.ui.control.Switch
        ControlLabel                matlab.ui.control.Label
        SwitchControl               matlab.ui.control.Switch
        RegistroTab                 matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        UITableDatos                matlab.ui.control.Table
        Panel_2                     matlab.ui.container.Panel
        RestablecerButton           matlab.ui.control.Button
        ActualizarButton            matlab.ui.control.Button
        IniciarcensadoButton        matlab.ui.control.Button
        NumdeplantaacensarDropDownLabel  matlab.ui.control.Label
        NumdeplantaacensarDropDown  matlab.ui.control.DropDown
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        MuySecoLabel                matlab.ui.control.Label
        SecoLabel_2                 matlab.ui.control.Label
        PocoHumedoLabel             matlab.ui.control.Label
        HumedoLabel_2               matlab.ui.control.Label
        Lamp_6                      matlab.ui.control.Lamp
        ExtremadamenteHumedoLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        webcamObject;
        imageObject; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function btnConexionRBPushed(app, event)
         
        end

        % Value changed function: fieldIDConexionRB
        function fieldIDConexionRBValueChanged(app, event)
            global  textFieldIDConexionRB
            %app.fieldIDConexionRB.Value = '192.168.0.101';
            textFieldIDConexionRB = app.fieldIDConexionRB.Value;
            
        end

        % Value changed function: btnConexionRB
        function btnConexionRBValueChanged(app, event)
            global valueBtnConexionRB;
            global textFieldIDConexionRB;
            global r opcMov;
            
            valueBtnConexionRB = app.btnConexionRB.Value;
            
            if valueBtnConexionRB == true
                app.btnConexionRB.Enable = false;
                app.ledConexionRB.Color = [0.80 0.80 0.80];
                app.labelErorrdeconexion.Visible = false;
                pause(0.00001)
                  try 
                    r = raspi(textFieldIDConexionRB);
   
                    app.btnConexionRB.Text = 'Desvincular';
                    app.btnConexionRB.Enable = true;
                    app.ledConexionRB.Color = [0.3 0.9 0.2]; 
                    app.btnConexionRBVideoControl.Enable = true;
                    
                catch ME
                    fprintf('Conexión con placa fallida\n');
                    app.labelErorrdeconexion.Visible = true;
                    app.fieldIDConexionRB.Value = '';
                    app.btnConexionRB.Enable = true;
                    app.btnConexionRB.Value = false;
                    app.btnConexionRB.Text = 'Vincular';
                    app.ledConexionRB.Color = [0.80 0.20 0.20];
                    app.btnConexionRBVideoControl.Enable = false;
                    app.SwitchVideo.Enable = false;
                    app.SwitchControl.Enable = false;
                    clear all;   
                    
                end
            else
                app.btnConexionRB.Enable = false; % Se inhabilita bontón de conexión
                pause(0.00001)
                activacionBomba('E',r);
                app.fieldIDConexionRB.Value = '';
                app.btnConexionRB.Enable = true; % Se habilita botón de conexión
                
                app.SwitchVideo.Enable = false;
                app.SwitchControl.Enable = false;
                app.btnConexionRBVideoControl.Enable = false; %Se inhabilita bontón de video y control
                app.btnConexionRBVideoControl.Value = false;
                app.VelocidadSlider.Enable = false;

                
                app.ButtonNeutro.BackgroundColor = [0.96 0.96 0.96];
                app.ButtonArriba.BackgroundColor = [0.96 0.96 0.96];
                app.ButtonAbajo.BackgroundColor = [0.96 0.96 0.96];
                app.ButtonIzquierda.BackgroundColor = [0.96 0.96 0.96];
                app.ButtonDerecha.BackgroundColor = [0.96 0.96 0.96];
                                
                app.btnConexionRB.Text = 'Conectarse';
                app.ledConexionRB.Color = [0.80 0.80 0.80];
                clear r
                clear all
                clc
            end
            
        end

        % Callback function
        function btnConexionRBVideoButtonPushed(app, event)
            
        end

        % Value changed function: btnConexionRBVideoControl
        function btnConexionRBVideoControlValueChanged(app, event)
            global r velM;
            global joy cam;
            global valueBtnConexionRB;
            global valuebtnConexionRBVideoControl;
            global valueArriba;
            valuebtnConexionRBVideoControl = app.btnConexionRBVideoControl.Value;
            conexionPines;
            pause(0.000001)

            if valuebtnConexionRBVideoControl == true
                app.SwitchVideo.Enable = true;
                app.SwitchControl.Enable = true;
                app.VelocidadSlider.Enable = true;
                cam = webcam(r,'Microsoft® LifeCam HD-6000 for (usb-0000:01:00.0-1.1)');
                joy = vrjoystick(1); % Inicializando Joystick
                pause(0.00001)
                
                 try    
                    while valuebtnConexionRBVideoControl == true
                        pause(0.0000001)
                        if (strcmp(app.SwitchControl.Value,'On'))
                           
                            joystick();
                            %velocidad;
                            if ax(2) >= sens %atras
                                app.ButtonAbajo.BackgroundColor = [0.65 0.65 0.65];
                                app.ButtonArriba.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonNeutro.BackgroundColor = [0.96 0.96 0.96];
                            elseif ax(2) <=-sens %adelante
                                app.ButtonArriba.BackgroundColor = [0.65 0.65 0.65];
                                app.ButtonAbajo.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonNeutro.BackgroundColor = [0.96 0.96 0.96];
                            elseif ax(1) >=sens %derecha
                                app.ButtonDerecha.BackgroundColor = [0.65 0.65 0.65];
                                app.ButtonIzquierda.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonNeutro.BackgroundColor = [0.96 0.96 0.96];
                            elseif ax(1) <=-sens %izquierda
                                app.ButtonIzquierda.BackgroundColor = [0.65 0.65 0.65];
                                app.ButtonDerecha.BackgroundColor = [0.96 0.96 0.96];   
                                app.ButtonNeutro.BackgroundColor = [0.96 0.96 0.96];   
                            else  %neutral
                                app.ButtonNeutro.BackgroundColor = [0.65 0.65 0.65];
                                app.ButtonArriba.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonAbajo.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonIzquierda.BackgroundColor = [0.96 0.96 0.96];
                                app.ButtonDerecha.BackgroundColor = [0.96 0.96 0.96];   
                             end
                        end
                        if (strcmp(app.SwitchVideo.Value,'On'))
                            
                            camara(cam);
                        end
                    end
                    clear cam;
                catch ME
                    fprintf('Conexión con control fallida\n');
                 end
            else
                app.SwitchVideo.Enable = false;
                app.SwitchControl.Enable = false;
                app.VelocidadSlider.Enable = false;
            end           
        end

        % Value changed function: SwitchVideo
        function SwitchVideoValueChanged(app, event)
            global valueSwitchVideo;
            valueSwitchVideo = app.SwitchVideo.Value;
            
        end

        % Value changed function: SwitchControl
        function SwitchControlValueChanged(app, event)
            global valueSwitchControl;
            valueSwitchControl = app.SwitchControl.Value;
            
        end

        % Button pushed function: ButtonAbajo
        function ButtonAbajoPushed(app, event)
            
        end

        % Button pushed function: ButtonArriba
        function ButtonArribaPushed(app, event)
         
        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderValueChanged(app, event)
            global velM r ACT1 ACT2;
            %velM = (app.VelocidadSlider.Value)/100
            if app.VelocidadSlider.Value == 0
                velM = 0;
            elseif app.VelocidadSlider.Value > 1 && app.VelocidadSlider.Value <= 20
                velM = 0.5 + (app.VelocidadSlider.Value/200);                    
            elseif app.VelocidadSlider.Value > 20 && app.VelocidadSlider.Value <= 40
                velM = 0.6 + (app.VelocidadSlider.Value/400);
            elseif app.VelocidadSlider.Value > 40 && app.VelocidadSlider.Value <= 60
                velM = 0.7 + (app.VelocidadSlider.Value/600);  
            elseif app.VelocidadSlider.Value > 60 && app.VelocidadSlider.Value <= 80
                velM = 0.8 + (app.VelocidadSlider.Value/800); 
            elseif app.VelocidadSlider.Value > 80 && app.VelocidadSlider.Value <= 100
                velM = 0.9 + (app.VelocidadSlider.Value/1000);  
            end
            velocidad;
        end

        % Button pushed function: ButtonNeutro
        function ButtonNeutroPushed(app, event)
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            
            T=readtable('C:\Users\kevin\Documents\MEGAsync\UTM\ProyectoHumedad\Proyecto Integrador de Ingenieria en Mecatrónica\DatosHumedad.txt'); %Leyendo Tabla
            % Copiando datos existentes 
            
            app.UITableDatos.Data=T;
            
            sz = size(T);%Tamaño de Matriz
            cantEle=sz(1,1);%Num. de elemento
            
            % Número de elementos
            for pos = 1: cantEle
                app.NumdeplantaacensarDropDown.Items(pos)={num2str(T.No(pos))};
            end
            app.NumdeplantaacensarDropDown.Items(cantEle+1)={num2str(T.No(cantEle)+1)};%Agregando un valor más
            app.NumdeplantaacensarDropDown.Value={num2str(T.No(cantEle)+1)};%Posicionando en valor por defecto 
            
            b=bar(app.UIAxes,T.No,T.Humedad,'FaceColor','g');
            b.FaceColor = 'flat';
    
            
            for pos = 1:cantEle
                if T.Humedad(pos) >= 0 & T.Humedad(pos) <= 50
                     b.CData(pos,:) = [0.26,0.91,0.17];
                end
                if T.Humedad(pos) >= 50 & T.Humedad(pos) <= 350
                     b.CData(pos,:) = [0.23,0.92,0.86];
                end
                if T.Humedad(pos) >= 350 & T.Humedad(pos) <= 600
                     b.CData(pos,:) = [0.17,0.48,0.91];
                end
                if T.Humedad(pos) >= 600 & T.Humedad(pos) <= 800
                     b.CData(pos,:) = [0.91,0.75,0.17];
                end
                if T.Humedad(pos) >= 800
                     b.CData(pos,:) = [0.91,0.17,0.17];
                end
            end
                        
        end

        % Value changed function: NumdeplantaacensarDropDown
        function NumdeplantaacensarDropDownValueChanged(app, event)
            value = app.NumdeplantaacensarDropDown.Value;
         
        end

        % Button pushed function: IniciarcensadoButton
        function IniciarcensadoButtonPushed(app, event)
            
            T=readtable('C:\Users\kevin\Documents\MEGAsync\UTM\ProyectoHumedad\Proyecto Integrador de Ingenieria en Mecatrónica\DatosHumedad.txt'); %Leyendo Tabla
            
            global r;
            
            opc=str2double(app.NumdeplantaacensarDropDown.Value);
            sz = size(T);%Tamaño de Matriz
            cantEle=sz(1,1);%Num. de elemento
            
            %prom=4535;
            pause(0.0001)
            movimientoServos(0,r)
            pause(1)
            movimientoServos(3,r)
            pause(1)
            movimientoServos(2,r)
            pause(1)
            movimientoServos(1,r)
            pause(1)
            prom=SensadoHumedad(r);
            pause(1)
            movimientoServos(0,r)
            pause(2)
            movimientoServos(3,r)
            pause(1)
            pause(0.0001)     
            Etiqueta=estatusHumedad(prom);
            
            if strcmp(app.NumdeplantaacensarDropDown.Value,' ')               
            elseif opc > cantEle
                archivosGuardados(1,prom,Etiqueta,datetime('now','Format','d-MM-y'),datestr(timeofday(datetime()),'hh:MM:ss'),opc);                                  
            elseif opc ==0
                archivosGuardados(1,prom,Etiqueta,datetime('now','Format','d-MM-y'),datestr(timeofday(datetime()),'hh:MM:ss'),opc);                                  
            else
                Etiqueta=estatusHumedad(prom);
                archivosGuardados(2,prom,Etiqueta,datetime('now','Format','d-MM-y'),datestr(timeofday(datetime()),'hh:MM:ss'),opc); 
            end
            clear T
            %*************************************
            %Actualizando
             
            T=readtable('C:\Users\kevin\Documents\MEGAsync\UTM\ProyectoHumedad\Proyecto Integrador de Ingenieria en Mecatrónica\DatosHumedad.txt'); %Leyendo Tabla
            % Copiando datos existentes 
            
            app.UITableDatos.Data=T;
            
            sz = size(T);%Tamaño de Matriz
            cantEle=sz(1,1);%Num. de elemento
            
            for pos = 1: cantEle
                app.NumdeplantaacensarDropDown.Items(pos)={num2str(T.No(pos))};
            end
            app.NumdeplantaacensarDropDown.Items(cantEle+1)={num2str(T.No(cantEle)+1)};
            app.NumdeplantaacensarDropDown.Value={num2str(T.No(cantEle)+1)};
            
            b=bar(app.UIAxes,T.No,T.Humedad,'FaceColor','g');
            b.FaceColor = 'flat';
    
            
            for pos = 1:cantEle
                if T.Humedad(pos) >= 0 & T.Humedad(pos) <= 50
                     b.CData(pos,:) = [0.26,0.91,0.17];
                end
                if T.Humedad(pos) >= 50 & T.Humedad(pos) <= 350
                     b.CData(pos,:) = [0.23,0.92,0.86];
                end
                if T.Humedad(pos) >= 350 & T.Humedad(pos) <= 600
                     b.CData(pos,:) = [0.17,0.48,0.91];
                end
                if T.Humedad(pos) >= 600 & T.Humedad(pos) <= 800
                     b.CData(pos,:) = [0.91,0.75,0.17];
                end
                if T.Humedad(pos) >= 800
                     b.CData(pos,:) = [0.91,0.17,0.17];
                end
            end
            
            %*******
            %*******
            
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            %seleccion= uiconfirm(uifigure,'Está a punto de borrar los datos almacenados','Confirmación','Options',{'Borrar','Cancelar'},'DefaultOption',2,'CancelOption',3,'Icon','warning')
            
            seleccion= questdlg('Está a punto de borrar los datos almacenados','Advertencia','Borrar','Cancelar','Cancelar');
            if strcmp(seleccion,'Borrar')
                archivosGuardados(0,0,'NA',datetime('now','Format','d-MM-y'),datestr(timeofday(datetime()),'hh:MM:ss'),0); 
            end
            clear T
            %Actualizando
            
            T=readtable('C:\Users\kevin\Documents\MEGAsync\UTM\ProyectoHumedad\Proyecto Integrador de Ingenieria en Mecatrónica\DatosHumedad.txt'); %Leyendo Tabla
            % Copiando datos existentes 
            
            app.UITableDatos.Data=T;
            
            sz = size(T);%Tamaño de Matriz
            cantEle=sz(1,1);%Num. de elemento
            app.NumdeplantaacensarDropDown.Items ={};
            for pos = 1: cantEle
                app.NumdeplantaacensarDropDown.Items(pos)={num2str(T.No(pos))};
            end
            app.NumdeplantaacensarDropDown.Items(cantEle+1)={num2str(T.No(cantEle)+1)};
            app.NumdeplantaacensarDropDown.Value={num2str(T.No(cantEle)+1)};
            
            b=bar(app.UIAxes,T.No,T.Humedad,'FaceColor','g');
            b.FaceColor = 'flat';
    
            
            for pos = 1:cantEle
                if T.Humedad(pos) >= 0 & T.Humedad(pos) <= 50
                     b.CData(pos,:) = [0.26,0.91,0.17];
                end
                if T.Humedad(pos) >= 50 & T.Humedad(pos) <= 350
                     b.CData(pos,:) = [0.23,0.92,0.86];
                end
                if T.Humedad(pos) >= 350 & T.Humedad(pos) <= 600
                     b.CData(pos,:) = [0.17,0.48,0.91];
                end
                if T.Humedad(pos) >= 600 & T.Humedad(pos) <= 800
                     b.CData(pos,:) = [0.91,0.75,0.17];
                end
                if T.Humedad(pos) >= 800
                     b.CData(pos,:) = [0.91,0.17,0.17];
                end
            end
            
            %*******
            
        end

        % Callback function
        function ButtonPushed(app, event)
%             cam=webcam();
%             while true
%                 im=camara(cam);
%                 app.UIAxesCamara = im;
%             end
            global r;
            r = raspi("192.168.1.90");
        end

        % Callback function
        function ButtonPushed2(app, event)
            app.NumdeplantaacensarDropDown.Value
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MandoTab = uitab(app.TabGroup);
            app.MandoTab.Title = 'Mando';
            app.Panel1Principal = uipanel(app.MandoTab);
            app.ButtonArriba = uibutton(app.Panel1Principal, 'push');
            app.ButtonArriba.ButtonPushedFcn = createCallbackFcn(app, @ButtonArribaPushed, true);
            app.ButtonArriba.Icon = 'arribaclick.png';
            app.ButtonArriba.Text = '';
            app.ButtonAbajo = uibutton(app.Panel1Principal, 'push');
            app.ButtonAbajo.ButtonPushedFcn = createCallbackFcn(app, @ButtonAbajoPushed, true);
            app.ButtonAbajo.Icon = 'abajoclick.png';
            app.ButtonAbajo.Text = '';
            app.ButtonIzquierda = uibutton(app.Panel1Principal, 'push');
            app.ButtonIzquierda.Icon = 'izqclick.png';
            app.ButtonIzquierda.Text = '';
            app.ButtonDerecha = uibutton(app.Panel1Principal, 'push');
            app.ButtonDerecha.Icon = 'derclick.png';
            app.ButtonDerecha.Text = '';
            app.ButtonNeutro = uibutton(app.Panel1Principal, 'push');
            app.ButtonNeutro.ButtonPushedFcn = createCallbackFcn(app, @ButtonNeutroPushed, true);
            app.ButtonNeutro.Icon = 'pauseclick.png';
            app.ButtonNeutro.Text = '';
            app.ledConexionRB = uilamp(app.Panel1Principal);
            app.btnConexionRB = uibutton(app.Panel1Principal, 'state');
            app.btnConexionRB.ValueChangedFcn = createCallbackFcn(app, @btnConexionRBValueChanged, true);
            app.btnConexionRB.Text = 'Vincular';
            app.labelErorrdeconexion = uilabel(app.Panel1Principal);
            app.labelErorrdeconexion.Visible = 'off';
            app.labelErorrdeconexion.Text = 'Error de conexión';
            app.IDEditFieldLabel = uilabel(app.Panel1Principal);
            app.IDEditFieldLabel.Text = 'ID: ';
            app.fieldIDConexionRB = uieditfield(app.Panel1Principal, 'text');
            app.fieldIDConexionRB.ValueChangedFcn = createCallbackFcn(app, @fieldIDConexionRBValueChanged, true);
            app.VelocidadSliderLabel = uilabel(app.Panel1Principal);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.Panel1Principal);
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderValueChanged, true);
            app.VelocidadSlider.Enable = 'off';
            app.btnConexionRBVideoControl = uibutton(app.Panel1Principal, 'state');
            app.btnConexionRBVideoControl.ValueChangedFcn = createCallbackFcn(app, @btnConexionRBVideoControlValueChanged, true);
            app.btnConexionRBVideoControl.Enable = 'off';
            app.btnConexionRBVideoControl.Text = 'Video y control';
            app.VideoLabel = uilabel(app.Panel1Principal);
            app.VideoLabel.Enable = 'off';
            app.VideoLabel.Text = 'Video';
            app.SwitchVideo = uiswitch(app.Panel1Principal, 'slider');
            app.SwitchVideo.ValueChangedFcn = createCallbackFcn(app, @SwitchVideoValueChanged, true);
            app.SwitchVideo.Enable = 'off';
            app.ControlLabel = uilabel(app.Panel1Principal);
            app.ControlLabel.Enable = 'off';
            app.ControlLabel.Text = 'Control';
            app.SwitchControl = uiswitch(app.Panel1Principal, 'slider');
            app.SwitchControl.ValueChangedFcn = createCallbackFcn(app, @SwitchControlValueChanged, true);
            app.SwitchControl.Enable = 'off';
            app.RegistroTab = uitab(app.TabGroup);
            app.RegistroTab.Title = 'Registro';
            app.UIAxes = uiaxes(app.RegistroTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Planta')
            ylabel(app.UIAxes, 'Humedad')
            app.UITableDatos = uitable(app.RegistroTab);
            app.UITableDatos.ColumnName = {'Num. Planta'; 'Humedad'; 'Etiqueta'; 'Día'; 'Hora'};
            app.UITableDatos.RowName = {};
            app.Panel_2 = uipanel(app.RegistroTab);
            app.RestablecerButton = uibutton(app.Panel_2, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.ActualizarButton = uibutton(app.Panel_2, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.IniciarcensadoButton = uibutton(app.Panel_2, 'push');
            app.IniciarcensadoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcensadoButtonPushed, true);
            app.IniciarcensadoButton.Text = 'Iniciar censado';
            app.NumdeplantaacensarDropDownLabel = uilabel(app.Panel_2);
            app.NumdeplantaacensarDropDownLabel.Text = 'Num. de planta a censar:';
            app.NumdeplantaacensarDropDown = uidropdown(app.Panel_2);
            app.NumdeplantaacensarDropDown.Items = {' '};
            app.NumdeplantaacensarDropDown.ValueChangedFcn = createCallbackFcn(app, @NumdeplantaacensarDropDownValueChanged, true);
            app.NumdeplantaacensarDropDown.Value = ' ';
            app.Lamp = uilamp(app.RegistroTab);
            app.Lamp_2 = uilamp(app.RegistroTab);
            app.Lamp_3 = uilamp(app.RegistroTab);
            app.Lamp_4 = uilamp(app.RegistroTab);
            app.MuySecoLabel = uilabel(app.RegistroTab);
            app.MuySecoLabel.Text = 'Muy Seco';
            app.SecoLabel_2 = uilabel(app.RegistroTab);
            app.SecoLabel_2.Text = 'Seco';
            app.PocoHumedoLabel = uilabel(app.RegistroTab);
            app.PocoHumedoLabel.Text = 'Poco Humedo';
            app.HumedoLabel_2 = uilabel(app.RegistroTab);
            app.HumedoLabel_2.Text = 'Humedo';
            app.Lamp_6 = uilamp(app.RegistroTab);
            app.ExtremadamenteHumedoLabel = uilabel(app.RegistroTab);
            app.ExtremadamenteHumedoLabel.Text = 'Extremadamente Humedo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppRobotH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end